classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DataTab                       matlab.ui.container.Tab
        SamplingRagemsEditField       matlab.ui.control.NumericEditField
        SamplingRagemsEditFieldLabel  matlab.ui.control.Label
        MessageEditField              matlab.ui.control.EditField
        MessageLabel                  matlab.ui.control.Label
        AnalyzedataButton             matlab.ui.control.Button
        TagEditField                  matlab.ui.control.EditField
        TagEditFieldLabel             matlab.ui.control.Label
        SelectdatafolderButton        matlab.ui.control.Button
        HighthresholdSlider           matlab.ui.control.Slider
        HighthresholdSliderLabel      matlab.ui.control.Label
        LowthresholdSlider            matlab.ui.control.Slider
        LowthresholdSliderLabel       matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        UIAxes3                       matlab.ui.control.UIAxes
        AnalysisTab                   matlab.ui.container.Tab
        RerunAUTOpeakdetectionButton  matlab.ui.control.Button
        ClearAUTOpeaksButton          matlab.ui.control.Button
        ExportDataButton              matlab.ui.control.Button
        UITable2                      matlab.ui.control.Table
        NextButton                    matlab.ui.control.Button
        PreviousButton                matlab.ui.control.Button
        DropDown                      matlab.ui.control.DropDown
        DropDownLabel                 matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
    end


% This program is free software: you can redistribute it and/or modify
%     it under the terms of the GNU General Public License as published by
%     the Free Software Foundation, either version 3 of the License, or
%     (at your option) any later version.
% 
%     This program is distributed in the hope that it will be useful,
%     but WITHOUT ANY WARRANTY; without even the implied warranty of
%     MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
%     GNU General Public License for more details.
% 
%     You should have received a copy of the GNU General Public License
%     along with this program.  If not, see <https://www.gnu.org/licenses/>.

    properties (Access = private)
        imageData % all image data from associated folder
        dataDir  % Location with the data stored
        iCtrl % Index of the control image
        totalControlMean % a time- average of all the control images
        imMask % mask for analysis
        dataTable % data table containing the description of the data
        dataLoaded % boolean value set when data is loaded
        indROI % indices of the image mask for analysis
        fadingControl % Description
        
        timeStep % Description
        firstImageIndex
        responseThreshold % Description
        diffResponseThreshold % Description
    end
    
    methods (Access = private)
        
        function updateThresholdedImage(app)
            if app.dataLoaded
                imshowpair(app.totalControlMean,app.imMask,'Parent',app.UIAxes3);
            end
            resetMessage(app);
            
            
        end
        
        function computeImMask(app)
            app.imMask=app.totalControlMean>app.LowthresholdSlider.Value & app.totalControlMean(:,:,1)<=app.HighthresholdSlider.Value; % & not(BW);
        end
        
        function drawControlImage(app)
            [N,edges]=histcounts(app.totalControlMean(:),0:1:1000);
            centers=edges(1:end-1)+diff(edges)/2;
            [~,locs,w,~]=findpeaks(N,centers,'MinPeakDistance',10,'SortStr','descend','Annotate','extents');
            
            pind=1;
            imagesc(app.UIAxes3,'CData',app.totalControlMean,[locs(pind)-w(pind),locs(pind)+3*w(pind)])
            %             imagesc(app.UIAxes,squeeze(app.totalControlMean))
            colormap(app.UIAxes3,"gray");
            %             app.UIAxes.View=[0,90];
            %             set(app.UIAxes,'View',[0,90]);
            %             colorbar(app.UIAxes,"east");
        end
        
        function formatDataTable(app)
            variableNames={'index','E_strength','Nimages'};
            
            NL=numel(app.imageData);
            NC=numel(variableNames);
            variableTypes=[repmat({'double'},1,NC)];
            dataTable=table('Size',[NL,NC],'VariableTypes',variableTypes,'VariableNames',variableNames);
            for ii=1:NL
                dataTable{ii,1}=ii;
                dataTable{ii,2}=app.imageData(ii).E;
                dataTable{ii,3}=size(app.imageData(ii).FStack,3);
            end
            app.dataTable=dataTable;
            app.UITable.Data=dataTable;
            
        end
        
        function resetMessage(app)
            app.MessageEditField.Value='';
            app.MessageEditField.FontColor=[0 0 0];
            app.MessageEditField.FontWeight='normal';
        end
        
        function [relativeFluorescence,meanFluorescence,fitMean,tim] = analyzeFluorescence(app,ind)
            FStack=app.imageData(ind).FStack;
            %             ECur=DataStruct(ind).E;
            nim=size(FStack,3);
            
            sData=zeros(numel(app.indROI),nim);
            for ii=1:nim
                imi=FStack(:,:,ii);
                sData(:,ii)=imi(app.indROI);
            end
            meanFluorescence=mean(sData);
            tim=1:nim;
            if ind==1
                [xData, yData] = prepareCurveData( tim, meanFluorescence );
                ft = fittype( 'poly1' );
                [fitresult, gof] = fit( xData, yData, ft );
                app.fadingControl=fitresult;
                app.imageData(ind).fitresult=fitresult;
                app.imageData(ind).gof=gof;
            else
                preExpMean=mean(sData(:,1:9),"all");
                pn=coeffvalues(app.fadingControl);
                slope=pn(1);
                intercept=preExpMean;
                fitresult=cfit(fittype('poly1'),slope,intercept);
            end
            
            
            % [resCtrl,gof]=createFitLinear(tim,meanFluorescence);
            % [res,gof]=createFit(tim,meanROI);
            fitMean=feval(fitresult,tim)';
            relativeFluorescence=meanFluorescence./fitMean;
        end
        
        function drawNormalizedFluorescence(app,ind)
            mycmap=lines(5);
            timeParameters=[25,50,75,90];
            timeVector=app.imageData(ind).tim;
            %             firstImageAfterPulse=10; % First image after the pulse
            timeVector=timeVector-app.firstImageIndex;
            timeVector=timeVector*app.timeStep;
            %             if not(isnan(app.imageData(ind).anomaly))
            %                 timeVe
            %             end
            hi=plot(app.UIAxes4,timeVector,app.imageData(ind).relativeFluorescence,"Color",mycmap(1,:),'HitTest','off');
%             set(hi,(app,event),'PickableParts','all');
            han(1)=hi;
            ylim(app.UIAxes4,[0.95 1.005])
            %     xlabel(handles(kk),'Image []')
            ylabel(app.UIAxes4,'Relative fluorescence (F/F_0) [AU]')
            title(app.UIAxes4,sprintf('%s Relative fluorescence %u V/cm',app.TagEditField.Value, app.imageData(ind).E))
            legText={'Relative Fluorescence'};


            indVector=app.imageData(ind).indMatrix;
            if not(isnan(indVector(1)))
                hold(app.UIAxes4,'on')
                IN1=indVector(1);
                xline(app.UIAxes4,timeVector(IN1))
            else
                return
            end
            for kk=2:numel(indVector)
                INn=indVector(kk);
                if not(isnan(INn))
                    hi=plot(app.UIAxes4,[timeVector(IN1),timeVector(INn)],[app.imageData(ind).valueVector(kk),app.imageData(ind).valueVector(kk)],"Color",mycmap(kk,:));
                    han(kk)=hi;
                    legAdd={sprintf('Recovery time to %u %%',timeParameters(kk-1))};
                    legText=[legText,legAdd];
                end
            end
            hold(app.UIAxes4,'off')
            legend(app.UIAxes4,han,legText,'Location','southeast')
        end


        function formatParameterTable(app)
            variableNames={'index','E_strength','NoPeaks','MPPT','MR','TtFP','T25','T50','T75','T90'};
            NL=numel(app.imageData);
            NC=numel(variableNames);
            variableTypes=[repmat({'double'},1,NC)];
            parameterTable=table('Size',[NL,NC],'VariableTypes',variableTypes,'VariableNames',variableNames);
            for ii=1:NL
                parameterTable{ii,1}=ii;
                parameterTable{ii,2}=app.imageData(ii).E;
                parameterTable{ii,3}=app.imageData(ii).NoPeaks;
                parameterTable{ii,4}=app.imageData(ii).MeanPkToPkTime;
                parameterTable{ii,5}=app.imageData(ii).MaxResp;
                parameterTable{ii,6}=app.imageData(ii).TtoFirstPeak;
                parameterTable{ii,7}=app.imageData(ii).T25;
                parameterTable{ii,8}=app.imageData(ii).T50;
                parameterTable{ii,9}=app.imageData(ii).T75;
                parameterTable{ii,10}=app.imageData(ii).T90;
            end
            
            app.UITable2.Data=parameterTable;
            app.UITable2.ColumnWidth={40,40,75,50,50,50,50,50,50,50};
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define pointer behavior when pointer enter axes
            pm.enterFcn = @(~,~) set(app.UIFigure, 'Pointer', 'crosshair');
            pm.exitFcn  = @(~,~) set(app.UIFigure, 'Pointer', 'arrow');
            pm.traverseFcn = [];
            iptSetPointerBehavior(app.UIAxes4, pm)
            % Enable pointer manager for app
            iptPointerManager(app.UIFigure,'enable');
        end

        % Button pushed function: SelectdatafolderButton
        function SelectdatafolderButtonPushed(app, event)
            app.dataDir=uigetdir;
            
            dd=dir(string(app.dataDir)+filesep()+"*.tif");
            app.TagEditField.Value=dd(1).name(1:end-11);
            
            for jj=1:length(dd)
                FStack=zeros(512,512,999,'uint16');
                ii=1;
                er=false;
                while er==false
                    try
                        IIn=imread([dd(jj).folder,filesep(),dd(jj).name],'Index',ii);
                        FStack(:,:,ii)=IIn;
                        ii=ii+1;
                    catch
                        er=true;
                        FStack(:,:,ii:end)=[];
                    end
                end
                DataStruct(jj).FStack=FStack;
                Evalue=dd(jj).name(end-10:end-7);
                Evalue=str2num(Evalue);
                DataStruct(jj).E=Evalue;
            end
            app.imageData=DataStruct;
            
            % Find control image
            Evalues=[DataStruct.E];
            app.iCtrl=find(Evalues==0);
            assert(not(isempty(app.iCtrl)),'Control not found')
            app.totalControlMean=mean(app.imageData(app.iCtrl).FStack,3);
            drawControlImage(app);
            formatDataTable(app);
            app.dataLoaded=true;
        end

        % Value changed function: LowthresholdSlider
        function LowthresholdSliderValueChanged(app, event)
            computeImMask(app);
            updateThresholdedImage(app);
            resetMessage(app);
        end

        % Value changed function: HighthresholdSlider
        function HighthresholdSliderValueChanged(app, event)
            computeImMask(app);
            updateThresholdedImage(app);
            resetMessage(app);
        end

        % Button pushed function: AnalyzedataButton
        function AnalyzedataButtonPushed(app, event)
            
            if app.dataLoaded
                % %%%%%%%%%
                % Constants - ask if we need to provide this as a edit
                % field
                
                app.responseThreshold=0.008;  % This value needs to be adjusted for NO DOX experiments
                app.diffResponseThreshold=0.008;
                timeStepHardCoded=36; % Sampling time
                firstImageAfterPulse=10; % First image after the pulse
                app.timeStep=timeStepHardCoded;
                app.firstImageIndex=firstImageAfterPulse;
                
                % %%%%%%%%%
                app.TabGroup.SelectedTab=app.AnalysisTab;
                app.indROI=find(app.imMask);
                NI=numel(app.imageData);
                DDItems=cell(1,NI);
                for ii=1:NI
                    [app.imageData(ii).relativeFluorescence,app.imageData(ii).rawFluorescence,app.imageData(ii).fitMean,app.imageData(ii).tim]=analyzeFluorescence(app, ii);
                    DDItems{ii}=sprintf('E=%u V/cm',app.imageData(ii).E);
                    [OP,app.imageData(ii).indMatrix,VV,app.imageData(ii).anomaly]=parametrizeFluoSignal(app.imageData(ii).relativeFluorescence,app.responseThreshold,app.diffResponseThreshold,app.timeStep,app.firstImageIndex);
                    app.imageData(ii).NoPeaks=OP(1);
                    app.imageData(ii).MeanPkToPkTime=OP(2);
                    app.imageData(ii).MaxResp=OP(3);
                    app.imageData(ii).TtoFirstPeak=OP(4);
                    app.imageData(ii).T25=OP(5);
                    app.imageData(ii).T50=OP(6);
                    app.imageData(ii).T75=OP(7);
                    app.imageData(ii).T90=OP(8);
                    app.imageData(ii).valueVector=VV;
                end
                formatParameterTable(app);
                app.DropDown.Items=DDItems;
                drawNormalizedFluorescence(app,1);
                %                 app.DropDow
            else
                app.MessageEditField.Value='No data loaded. Please load data first!';
                app.MessageEditField.FontColor=[1 0 0];
                app.MessageEditField.FontWeight='bold';
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            items= app.DropDown.Items;
            equal=strcmp(items,value);
            chosenInd=find(equal);
            drawNormalizedFluorescence(app,chosenInd);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            items=app.DropDown.Items;
            value=app.DropDown.Value;
            equal=strcmp(items,value);
            curI=find(equal);
            if curI>1
                curI=curI-1;
                app.DropDown.Value=items{curI};
                DropDownValueChanged(app,1);
            end
            
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            items=app.DropDown.Items;
            value=app.DropDown.Value;
            equal=strcmp(items,value);
            curI=find(equal);
            if curI<numel(items)
                curI=curI+1;
                app.DropDown.Value=items{curI};
                DropDownValueChanged(app,1);
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            outDir=app.dataDir;
            lastFolder=split(outDir,filesep());
            lastFolder=lastFolder{end};
            tableFname=sprintf('%s_%s_analysis.xlsx',lastFolder,app.TagEditField.Value);
            matFname=sprintf('%s_%s_analysis.mat',lastFolder,app.TagEditField.Value);
            TN=fullfile(outDir,tableFname);
            MN=fullfile(outDir,matFname);
            sheetName='Parameters';
            writetable(app.UITable2.Data,TN,'Sheet',sheetName);
            IMD=app.imageData;
            LoThresh=app.LowthresholdSlider.Value;
            HiThresh=app.HighthresholdSlider.Value;
            save(MN,'IMD','LoThresh','HiThresh')
            for ii=1:numel(app.imageData)
                sheetName=sprintf('%u Vcm',app.imageData(ii).E);
                t=app.imageData(ii).tim;
                t=t-app.firstImageIndex;
                t=t*app.timeStep;
                t=t';
                meanFluo=app.imageData(ii).rawFluorescence;
                relFluo=app.imageData(ii).relativeFluorescence;
                fittedMean=app.imageData(ii).fitMean;
                TOut=table(t,meanFluo',relFluo',fittedMean','VariableNames',{'Time','MeanFluorescence','RelativeFluorescence','FittedMean'});
                writetable(TOut,TN,'Sheet',sheetName);
                
                % TODO save images
                drawNormalizedFluorescence(app,ii);
                imageFileName=sprintf('%s_%s_image_%02u.png',lastFolder,app.TagEditField.Value,ii);
                IN=fullfile(outDir,imageFileName);
                exportgraphics(app.UIAxes4,IN,"Resolution",300)
            end
            
            
        end

        % Button pushed function: ClearAUTOpeaksButton
        function ClearAUTOpeaksButtonPushed(app, event)
            items=app.DropDown.Items;
            value=app.DropDown.Value;
            equal=strcmp(items,value);
            curI=find(equal);
            %             app.imageData(ii).E;
            app.imageData(curI).indMatrix=nan(1,5);
            app.imageData(curI).NoPeaks=NaN;
            app.imageData(curI).MeanPkToPkTime=NaN;
            app.imageData(curI).MaxResp=NaN;
            app.imageData(curI).TtoFirstPeak=NaN;
            app.imageData(curI).T25=NaN;
            app.imageData(curI).T50=NaN;
            app.imageData(curI).T75=NaN;
            app.imageData(curI).T90=NaN;
            formatParameterTable(app);
            drawNormalizedFluorescence(app,curI);
        end

        % Button down function: UIAxes4
        function UIAxes4ButtonDown(app, event)
            userPeak=app.UIAxes4.CurrentPoint; % we get the point of the cursor
            userPeakTime=userPeak(1); % Time is in the first element
            items=app.DropDown.Items;
            value=app.DropDown.Value;
            equal=strcmp(items,value);
            curI=find(equal);
            timeVector=app.imageData(curI).tim;
            %             firstImageAfterPulse=10; % First image after the pulse

            timeVector=timeVector-app.firstImageIndex;
            timeVector=timeVector*app.timeStep;
            newPeakIndex=find(userPeakTime<=timeVector,1,"first");
            [OP,app.imageData(curI).indMatrix,VV,~]=manualPeakFluoSignal(app.imageData(curI).relativeFluorescence,app.responseThreshold,app.timeStep,app.firstImageIndex,newPeakIndex);
            app.imageData(curI).NoPeaks=OP(1);
            app.imageData(curI).MeanPkToPkTime=OP(2);
            app.imageData(curI).MaxResp=OP(3);
            app.imageData(curI).TtoFirstPeak=OP(4);
            app.imageData(curI).T25=OP(5);
            app.imageData(curI).T50=OP(6);
            app.imageData(curI).T75=OP(7);
            app.imageData(curI).T90=OP(8);
            app.imageData(curI).valueVector=VV;
            formatParameterTable(app);
            drawNormalizedFluorescence(app,curI);
        end

        % Button pushed function: RerunAUTOpeakdetectionButton
        function RerunAUTOpeakdetectionButtonPushed(app, event)
            userPeak=app.UIAxes4.CurrentPoint; % we get the point of the cursor
            userPeakTime=userPeak(1); % Time is in the first element
            items=app.DropDown.Items;
            value=app.DropDown.Value;
            equal=strcmp(items,value);
            curI=find(equal);

            [OP,app.imageData(curI).indMatrix,VV,app.imageData(curI).anomaly]=parametrizeFluoSignal(app.imageData(curI).relativeFluorescence,app.responseThreshold,app.diffResponseThreshold,app.timeStep,app.firstImageIndex);
            app.imageData(curI).NoPeaks=OP(1);
            app.imageData(curI).MeanPkToPkTime=OP(2);
            app.imageData(curI).MaxResp=OP(3);
            app.imageData(curI).TtoFirstPeak=OP(4);
            app.imageData(curI).T25=OP(5);
            app.imageData(curI).T50=OP(6);
            app.imageData(curI).T75=OP(7);
            app.imageData(curI).T90=OP(8);
            app.imageData(curI).valueVector=VV;
            formatParameterTable(app);
            drawNormalizedFluorescence(app,curI);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UIAxes3 = uiaxes(app.DataTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Ind'; 'E'; 'Nim'};
            app.UITable.RowName = {};
            app.LowthresholdSliderLabel = uilabel(app.DataTab);
            app.LowthresholdSliderLabel.Text = 'Low threshold';
            app.LowthresholdSlider = uislider(app.DataTab);
            app.LowthresholdSlider.Limits = [100 500];
            app.LowthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @LowthresholdSliderValueChanged, true);
            app.LowthresholdSlider.Value = 250;
            app.HighthresholdSliderLabel = uilabel(app.DataTab);
            app.HighthresholdSliderLabel.Text = 'High threshold';
            app.HighthresholdSlider = uislider(app.DataTab);
            app.HighthresholdSlider.Limits = [200 1000];
            app.HighthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @HighthresholdSliderValueChanged, true);
            app.HighthresholdSlider.Value = 400;
            app.SelectdatafolderButton = uibutton(app.DataTab, 'push');
            app.SelectdatafolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdatafolderButtonPushed, true);
            app.SelectdatafolderButton.Text = 'Select data folder';
            app.TagEditFieldLabel = uilabel(app.DataTab);
            app.TagEditFieldLabel.Text = 'Tag';
            app.TagEditField = uieditfield(app.DataTab, 'text');
            app.AnalyzedataButton = uibutton(app.DataTab, 'push');
            app.AnalyzedataButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzedataButtonPushed, true);
            app.AnalyzedataButton.Text = 'Analyze data';
            app.MessageLabel = uilabel(app.DataTab);
            app.MessageLabel.Text = 'Message:';
            app.MessageEditField = uieditfield(app.DataTab, 'text');
            app.MessageEditField.Editable = 'off';
            app.SamplingRagemsEditFieldLabel = uilabel(app.DataTab);
            app.SamplingRagemsEditFieldLabel.Text = 'Sampling Rage [ms]';
            app.SamplingRagemsEditField = uieditfield(app.DataTab, 'numeric');
            app.SamplingRagemsEditField.Limits = [1 1000];
            app.SamplingRagemsEditField.Value = 36;
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.UIAxes4 = uiaxes(app.AnalysisTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.DropDownLabel = uilabel(app.AnalysisTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.AnalysisTab);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.PreviousButton = uibutton(app.AnalysisTab, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.AnalysisTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.UITable2 = uitable(app.AnalysisTab);
            app.UITable2.ColumnName = {'N'; 'E'; 'NoPeaks'; 'MPPT'; 'MR'; 'TtFP'; 'T25'; 'T50'; 'T75'; 'T90'};
            app.UITable2.RowName = {};
            app.ExportDataButton = uibutton(app.AnalysisTab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.ClearAUTOpeaksButton = uibutton(app.AnalysisTab, 'push');
            app.ClearAUTOpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAUTOpeaksButtonPushed, true);
            app.ClearAUTOpeaksButton.Text = 'Clear AUTO peaks';
            app.RerunAUTOpeakdetectionButton = uibutton(app.AnalysisTab, 'push');
            app.RerunAUTOpeakdetectionButton.ButtonPushedFcn = createCallbackFcn(app, @RerunAUTOpeakdetectionButtonPushed, true);
            app.RerunAUTOpeakdetectionButton.Text = 'Rerun AUTO peak detection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end